classdef model_simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        TranscriptionrateTALEnMminSliderLabel  matlab.ui.control.Label
        TranscriptionrateTALEnMminSlider  matlab.ui.control.Slider
        TranscriptionrateGOInMminSliderLabel  matlab.ui.control.Label
        TranscriptionrateGOInMminSlider  matlab.ui.control.Slider
        TranslationrateTALEnMminSliderLabel  matlab.ui.control.Label
        TranslationrateTALEnMminSlider  matlab.ui.control.Slider
        TranslationrateGOInMminSliderLabel  matlab.ui.control.Label
        TranslationrateGOInMminSlider   matlab.ui.control.Slider
        minimumcopynumberEditFieldLabel  matlab.ui.control.Label
        minimumcopynumberEditField      matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        maximumcopynumberEditFieldLabel  matlab.ui.control.Label
        maximumcopynumberEditField      matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TranslationrateGOInMminSlider.Value = 3.65;
            app.TranslationrateTALEnMminSlider.Value = 0.44;
            app.TranscriptionrateGOInMminSlider.Value = 3.78;
            app.TranscriptionrateTALEnMminSlider.Value = 1.03;
            
            c_min = app.minimumcopynumberEditField.Value;
            c_max = app.maximumcopynumberEditField.Value;
            b_G = app.TranslationrateGOInMminSlider.Value;
            b_T = app.TranslationrateTALEnMminSlider.Value;
            a_G = app.TranscriptionrateGOInMminSlider.Value;
            a_T = app.TranscriptionrateTALEnMminSlider.Value;
            copynumbers = c_min:c_max;
            err = get_error(a_T, b_T, c_min);
            cat = categorical("Error");
            %analytical = analytical_solution(a_G, a_T, b_G, b_T).*ones(length(copynumbers),1);
            analytical = zeros(length(copynumbers), 1);
            for i = 1:length(copynumbers)
                analytical(i) = analytical_solution2(a_G, a_T, b_G, b_T, copynumbers(i));
            end
            plot(app.UIAxes, copynumbers, analytical)
            bar(app.UIAxes2, cat, err);
            set(app.UIAxes, 'ylim', [0 1.5*analytical(1,1)]);
        end

        % Value changed function: TranscriptionrateGOInMminSlider, 
        % TranscriptionrateTALEnMminSlider, 
        % TranslationrateGOInMminSlider, TranslationrateTALEnMminSlider, 
        % maximumcopynumberEditField, minimumcopynumberEditField
        function ChangePlot(app, event)
            c_min = app.minimumcopynumberEditField.Value;
            c_max = app.maximumcopynumberEditField.Value;
            b_G = app.TranslationrateGOInMminSlider.Value;
            b_T = app.TranslationrateTALEnMminSlider.Value;
            a_G = app.TranscriptionrateGOInMminSlider.Value;
            a_T = app.TranscriptionrateTALEnMminSlider.Value;
            copynumbers = c_min:c_max;
            err = get_error(a_T, b_T, c_min);
            cat = categorical("Error");
            %analytical = analytical_solution(a_G, a_T, b_G, b_T).*ones(length(copynumbers),1);
            analytical = zeros(length(copynumbers), 1);
            for i = 1:length(copynumbers)
                analytical(i) = analytical_solution2(a_G, a_T, b_G, b_T, copynumbers(i));
            end
            
            plot(app.UIAxes, copynumbers, analytical)
            bar(app.UIAxes2, cat, err);
            set(app.UIAxes, 'ylim', [0 1.5*analytical(1,1)]);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Stabilized expression of GOI')
            xlabel(app.UIAxes, 'Copy number')
            ylabel(app.UIAxes, 'GOI (nM)')
            app.TranscriptionrateTALEnMminSliderLabel = uilabel(app.UIFigure);
            app.TranscriptionrateTALEnMminSliderLabel.Text = {'Transcription rate'; 'TALE (nM/min)'};
            app.TranscriptionrateTALEnMminSlider = uislider(app.UIFigure);
            app.TranscriptionrateTALEnMminSlider.Limits = [0 10];
            app.TranscriptionrateTALEnMminSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.TranscriptionrateTALEnMminSlider.ValueChangedFcn = createCallbackFcn(app, @ChangePlot, true);
            app.TranscriptionrateGOInMminSliderLabel = uilabel(app.UIFigure);
            app.TranscriptionrateGOInMminSliderLabel.Text = {'Transcription rate'; 'GOI (nM/min)'};
            app.TranscriptionrateGOInMminSlider = uislider(app.UIFigure);
            app.TranscriptionrateGOInMminSlider.Limits = [0 10];
            app.TranscriptionrateGOInMminSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.TranscriptionrateGOInMminSlider.ValueChangedFcn = createCallbackFcn(app, @ChangePlot, true);
            app.TranslationrateTALEnMminSliderLabel = uilabel(app.UIFigure);
            app.TranslationrateTALEnMminSliderLabel.Text = {'Translation rate'; 'TALE (nM/min)'};
            app.TranslationrateTALEnMminSlider = uislider(app.UIFigure);
            app.TranslationrateTALEnMminSlider.Limits = [0 10];
            app.TranslationrateTALEnMminSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.TranslationrateTALEnMminSlider.ValueChangedFcn = createCallbackFcn(app, @ChangePlot, true);
            app.TranslationrateGOInMminSliderLabel = uilabel(app.UIFigure);
            app.TranslationrateGOInMminSliderLabel.Text = {'Translation rate'; 'GOI (nM/min)'};
            app.TranslationrateGOInMminSlider = uislider(app.UIFigure);
            app.TranslationrateGOInMminSlider.Limits = [0 10];
            app.TranslationrateGOInMminSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.TranslationrateGOInMminSlider.ValueChangedFcn = createCallbackFcn(app, @ChangePlot, true);
            app.minimumcopynumberEditFieldLabel = uilabel(app.UIFigure);
            app.minimumcopynumberEditFieldLabel.Text = {'minimum'; ' copy number'};
            app.minimumcopynumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.minimumcopynumberEditField.ValueChangedFcn = createCallbackFcn(app, @ChangePlot, true);
            app.minimumcopynumberEditField.Value = 1;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Error estimation')
            ylabel(app.UIAxes2, 'Error')
            app.maximumcopynumberEditFieldLabel = uilabel(app.UIFigure);
            app.maximumcopynumberEditFieldLabel.Text = {'maximum'; ' copy number'};
            app.maximumcopynumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.maximumcopynumberEditField.ValueChangedFcn = createCallbackFcn(app, @ChangePlot, true);
            app.maximumcopynumberEditField.Value = 100;
        end
    end

    methods (Access = public)

        % Construct app
        function app = model_simulation

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end