classdef model_simulation_full < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        TranscriptionrateTALEnMminSliderLabel  matlab.ui.control.Label
        TranscriptionrateTALEnMminSlider  matlab.ui.control.Slider
        TranscriptionrateGOInMminSliderLabel  matlab.ui.control.Label
        TranscriptionrateGOInMminSlider  matlab.ui.control.Slider
        TranslationrateTALE1minSliderLabel  matlab.ui.control.Label
        TranslationrateTALE1minSlider  matlab.ui.control.Slider
        TranslationrateGOI1minSliderLabel  matlab.ui.control.Label
        TranslationrateGOI1minSlider   matlab.ui.control.Slider
        minimumcopynumberEditFieldLabel  matlab.ui.control.Label
        minimumcopynumberEditField     matlab.ui.control.NumericEditField
        maximumcopynumberEditFieldLabel  matlab.ui.control.Label
        maximumcopynumberEditField     matlab.ui.control.NumericEditField
        ProgressLampLabel              matlab.ui.control.Label
        ProgressLamp                   matlab.ui.control.Lamp
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TranslationrateGOI1minSlider.Value = 3; %3.65;
            app.TranslationrateTALE1minSlider.Value = 1; %0.44;
            app.TranscriptionrateGOInMminSlider.Value = 10; %10.5545;
            app.TranscriptionrateTALEnMminSlider.Value = 2; %2.8305;
            
            c_min = app.minimumcopynumberEditField.Value;
            c_max = app.maximumcopynumberEditField.Value;
            b_G = app.TranslationrateGOI1minSlider.Value;
            b_T = app.TranslationrateTALE1minSlider.Value;
            a_G = app.TranscriptionrateGOInMminSlider.Value;
            a_T = app.TranscriptionrateTALEnMminSlider.Value;
            copynumbers = c_min:c_max;
            
            tspan = [0 60*16];
            x0 = zeros(6,1);
            
            steady_states = zeros(length(copynumbers),1);
            for i = 1:length(copynumbers)
                x0(3) = copynumbers(i);
                [t, x] = ode15s(@(t,x) full_solution(x, a_G, a_T, b_G, b_T), tspan, x0);
                steady_states(i) = x(end,6);
            end
            
            plot(app.UIAxes, copynumbers, steady_states)
            set(app.UIAxes, 'ylim', [0 1.5*steady_states(1,1)]);
        end

        % Value changed function: TranscriptionrateGOInMminSlider, 
        % TranscriptionrateTALEnMminSlider, 
        % TranslationrateGOI1minSlider, TranslationrateTALE1minSlider, 
        % maximumcopynumberEditField, minimumcopynumberEditField
        function ChangePlot(app, event)
            app.ProgressLamp.Color = 'red';
            pause(0.1);
            c_min = app.minimumcopynumberEditField.Value;
            c_max = app.maximumcopynumberEditField.Value;
            b_G = app.TranslationrateGOI1minSlider.Value;
            b_T = app.TranslationrateTALE1minSlider.Value;
            a_G = app.TranscriptionrateGOInMminSlider.Value;
            a_T = app.TranscriptionrateTALEnMminSlider.Value;
            copynumbers = c_min:c_max;
            
            tspan = [0 60*16];
            x0 = zeros(6,1);
            
            steady_states = zeros(length(copynumbers),1);
            for i = 1:length(copynumbers)
                x0(3) = copynumbers(i);
                [t, x] = ode15s(@(t,x) full_solution(x, a_G, a_T, b_G, b_T), tspan, x0);
                steady_states(i) = x(end,6);
            end
            
            plot(app.UIAxes, copynumbers, steady_states)
            set(app.UIAxes, 'ylim', [0 1.5*steady_states(1,1)]);
            app.ProgressLamp.Color = 'green';
            pause(0.1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Stabilized expression of GOI')
            xlabel(app.UIAxes, 'Copy number')
            ylabel(app.UIAxes, 'GOI (nM)')
            app.TranscriptionrateTALEnMminSliderLabel = uilabel(app.UIFigure);
            app.TranscriptionrateTALEnMminSliderLabel.Text = {'Transcription rate'; 'TALE (nM/min)'};
            app.TranscriptionrateTALEnMminSlider = uislider(app.UIFigure);
            app.TranscriptionrateTALEnMminSlider.Limits = [0 10];
            app.TranscriptionrateTALEnMminSlider.ValueChangedFcn = createCallbackFcn(app, @ChangePlot, true);
            app.TranscriptionrateGOInMminSliderLabel = uilabel(app.UIFigure);
            app.TranscriptionrateGOInMminSliderLabel.Text = {'Transcription rate'; 'GOI (nM/min)'};
            app.TranscriptionrateGOInMminSlider = uislider(app.UIFigure);
            app.TranscriptionrateGOInMminSlider.Limits = [0 20];
            app.TranscriptionrateGOInMminSlider.ValueChangedFcn = createCallbackFcn(app, @ChangePlot, true);
            app.TranslationrateTALE1minSliderLabel = uilabel(app.UIFigure);
            app.TranslationrateTALE1minSliderLabel.Text = {'Translation rate'; 'TALE (1/min)'};
            app.TranslationrateTALE1minSlider = uislider(app.UIFigure);
            app.TranslationrateTALE1minSlider.Limits = [0 10];
            app.TranslationrateTALE1minSlider.ValueChangedFcn = createCallbackFcn(app, @ChangePlot, true);
            app.TranslationrateGOI1minSliderLabel = uilabel(app.UIFigure);
            app.TranslationrateGOI1minSliderLabel.Text = {'Translation rate'; 'GOI (1/min)'};
            app.TranslationrateGOI1minSlider = uislider(app.UIFigure);
            app.TranslationrateGOI1minSlider.Limits = [0 10];
            app.TranslationrateGOI1minSlider.ValueChangedFcn = createCallbackFcn(app, @ChangePlot, true);
            app.minimumcopynumberEditFieldLabel = uilabel(app.UIFigure);
            app.minimumcopynumberEditFieldLabel.Text = {'minimum'; ' copy number'};
            app.minimumcopynumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.minimumcopynumberEditField.ValueChangedFcn = createCallbackFcn(app, @ChangePlot, true);
            app.minimumcopynumberEditField.Value = 1;
            app.maximumcopynumberEditFieldLabel = uilabel(app.UIFigure);
            app.maximumcopynumberEditFieldLabel.Text = {'maximum'; ' copy number'};
            app.maximumcopynumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.maximumcopynumberEditField.ValueChangedFcn = createCallbackFcn(app, @ChangePlot, true);
            app.maximumcopynumberEditField.Value = 100;
            app.ProgressLampLabel = uilabel(app.UIFigure);
            app.ProgressLampLabel.Text = 'Progress:';
            app.ProgressLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = model_simulation_full

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end